classdef MATLAB_CROP_PLANNER1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        EditField_66                  matlab.ui.control.EditField
        ReloadAppButton               matlab.ui.control.Button
        AIPredictedCropYieldPotentialinSelectedLocationPanel  matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        ImportFileButton              matlab.ui.control.Button
        SectionOne_2                  matlab.ui.container.Panel
        WaterRetentionRecommendationTextArea  matlab.ui.control.TextArea
        WaterRetentionRecommendationLabel  matlab.ui.control.Label
        SoilpHRecommendationTextArea  matlab.ui.control.TextArea
        SoilpHRecommendationLabel     matlab.ui.control.Label
        WaterRetentionDropDown        matlab.ui.control.DropDown
        WaterRetentionDropDownLabel   matlab.ui.control.Label
        SoilPHDropDown                matlab.ui.control.DropDown
        SoilPHDropDownLabel           matlab.ui.control.Label
        SectionOne                    matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        CropDropDown                  matlab.ui.control.DropDown
        CropDropDownLabel             matlab.ui.control.Label
        SoilTypeDropDown              matlab.ui.control.DropDown
        SoilTypeDropDownLabel         matlab.ui.control.Label
        LocationDropDown              matlab.ui.control.DropDown
        LocationDropDownLabel         matlab.ui.control.Label
        TemperatureMapPanel           matlab.ui.container.Panel
        RecommendationsbasedonAIpredictionPanel  matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        AIPredictedCropPlanningSchedulePanel  matlab.ui.container.Panel
        EditField_65                  matlab.ui.control.EditField
        EditField_64                  matlab.ui.control.EditField
        EditField_63                  matlab.ui.control.EditField
        EditField_62                  matlab.ui.control.EditField
        EditField_61                  matlab.ui.control.EditField
        Dec_Seed_Preparation          matlab.ui.control.Lamp
        Dec_Soil_Preparation          matlab.ui.control.Lamp
        Nov_Seed_Preparation          matlab.ui.control.Lamp
        Nov_Soil_Preparation          matlab.ui.control.Lamp
        Oct_Seed_Preparation          matlab.ui.control.Lamp
        Oct_Soil_Preparation          matlab.ui.control.Lamp
        Sep_Seed_Preparation          matlab.ui.control.Lamp
        Sep_Soil_Preparation          matlab.ui.control.Lamp
        Aug_Seed_Preparation          matlab.ui.control.Lamp
        Aug_Soil_Preparation          matlab.ui.control.Lamp
        Jul_Seed_Preparation          matlab.ui.control.Lamp
        Jul_Soil_Preparation          matlab.ui.control.Lamp
        Jun_Seed_Preparation          matlab.ui.control.Lamp
        Jun_Soil_Preparation          matlab.ui.control.Lamp
        May_Seed_Preparation          matlab.ui.control.Lamp
        May_Soil_Preparation          matlab.ui.control.Lamp
        Apr_Seed_Preparation          matlab.ui.control.Lamp
        Apr_Soil_Preparation          matlab.ui.control.Lamp
        Mar_Seed_Preparation          matlab.ui.control.Lamp
        Mar_Soil_Preparation          matlab.ui.control.Lamp
        Feb_Seed_Preparation          matlab.ui.control.Lamp
        Feb_Soil_Preparation          matlab.ui.control.Lamp
        Jan_Seed_Preparation          matlab.ui.control.Lamp
        Jan_Soil_Preparation          matlab.ui.control.Lamp
        EditField_60                  matlab.ui.control.EditField
        EditField_59                  matlab.ui.control.EditField
        EditField_58                  matlab.ui.control.EditField
        EditField_57                  matlab.ui.control.EditField
        EditField_56                  matlab.ui.control.EditField
        EditField_55                  matlab.ui.control.EditField
        EditField_54                  matlab.ui.control.EditField
        EditField_53                  matlab.ui.control.EditField
        EditField_52                  matlab.ui.control.EditField
        EditField_51                  matlab.ui.control.EditField
        EditField_50                  matlab.ui.control.EditField
        EditField_49                  matlab.ui.control.EditField
        EditField_48                  matlab.ui.control.EditField
        EditField_47                  matlab.ui.control.EditField
        EditField_46                  matlab.ui.control.EditField
        EditField_45                  matlab.ui.control.EditField
        EditField_44                  matlab.ui.control.EditField
        EditField_43                  matlab.ui.control.EditField
        EditField_42                  matlab.ui.control.EditField
        EditField_41                  matlab.ui.control.EditField
        EditField_40                  matlab.ui.control.EditField
        EditField_39                  matlab.ui.control.EditField
        EditField_38                  matlab.ui.control.EditField
        EditField_37                  matlab.ui.control.EditField
        Dec_Harvest                   matlab.ui.control.Lamp
        Dec_Planting                  matlab.ui.control.Lamp
        Nov_Harvest                   matlab.ui.control.Lamp
        Nov_Planting                  matlab.ui.control.Lamp
        Oct_Harvest                   matlab.ui.control.Lamp
        Oct_Planting                  matlab.ui.control.Lamp
        Sep_Harvest                   matlab.ui.control.Lamp
        Sep_Planting                  matlab.ui.control.Lamp
        Aug_Harvest                   matlab.ui.control.Lamp
        Aug_Planting                  matlab.ui.control.Lamp
        Jul_Harvest                   matlab.ui.control.Lamp
        Jul_Planting                  matlab.ui.control.Lamp
        Jun_Harvest                   matlab.ui.control.Lamp
        Jun_Planting                  matlab.ui.control.Lamp
        May_Harvest                   matlab.ui.control.Lamp
        May_Planting                  matlab.ui.control.Lamp
        Apr_Harvest                   matlab.ui.control.Lamp
        Apr_Planting                  matlab.ui.control.Lamp
        Mar_Harvest                   matlab.ui.control.Lamp
        Mar_Planting                  matlab.ui.control.Lamp
        Feb_Harvest                   matlab.ui.control.Lamp
        Feb_Planting                  matlab.ui.control.Lamp
        Jan_Harvest                   matlab.ui.control.Lamp
        Jan_Planting                  matlab.ui.control.Lamp
        EditField_36                  matlab.ui.control.EditField
        EditField_35                  matlab.ui.control.EditField
        EditField_34                  matlab.ui.control.EditField
        EditField_33                  matlab.ui.control.EditField
        EditField_32                  matlab.ui.control.EditField
        EditField_31                  matlab.ui.control.EditField
        EditField_30                  matlab.ui.control.EditField
        EditField_29                  matlab.ui.control.EditField
        EditField_28                  matlab.ui.control.EditField
        EditField_27                  matlab.ui.control.EditField
        EditField_26                  matlab.ui.control.EditField
        EditField_25                  matlab.ui.control.EditField
        EditField_24                  matlab.ui.control.EditField
        EditField_23                  matlab.ui.control.EditField
        EditField_22                  matlab.ui.control.EditField
        EditField_21                  matlab.ui.control.EditField
        EditField_20                  matlab.ui.control.EditField
        EditField_19                  matlab.ui.control.EditField
        EditField_18                  matlab.ui.control.EditField
        EditField_17                  matlab.ui.control.EditField
        EditField_16                  matlab.ui.control.EditField
        EditField_15                  matlab.ui.control.EditField
        EditField_14                  matlab.ui.control.EditField
        EditField_13                  matlab.ui.control.EditField
        EditField_12                  matlab.ui.control.EditField
        EditField_11                  matlab.ui.control.EditField
        EditField_10                  matlab.ui.control.EditField
        EditField_9                   matlab.ui.control.EditField
        EditField_8                   matlab.ui.control.EditField
        EditField_7                   matlab.ui.control.EditField
        EditField_6                   matlab.ui.control.EditField
        EditField_5                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        EditField                     matlab.ui.control.EditField
        AIPredictedPlantingSchedulePanel  matlab.ui.container.Panel
        SoilPreparationTimeEditField  matlab.ui.control.EditField
        SoilPreparationTimeEditFieldLabel  matlab.ui.control.Label
        SeedPreparationTimeEditField  matlab.ui.control.EditField
        SeedPreparationTimeEditFieldLabel  matlab.ui.control.Label
        HarvestingtimeEditField       matlab.ui.control.EditField
        HarvestingtimeEditFieldLabel  matlab.ui.control.Label
        PlantingTimeEditField         matlab.ui.control.EditField
        PlantingTimeEditFieldLabel    matlab.ui.control.Label
        ViewPersonalizedPlanButton    matlab.ui.control.Button
        Image3                        matlab.ui.control.Image
    end


    properties (Access = private)

        historicalData
        climateData
        cropRequirementsData
        soilCropData
        % Loading of all necessary datasets
    end
    methods (Access = public)

        % this function is used to reset all lamps to the default grey color
        % using a for loop
        function resetAllLamps(app, defaultColor)
            months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
            suffixes = {'_Planting', '_Harvest', '_Soil_Preparation', '_Seed_Preparation'};
            % these are the lamp suffixes for planting, harvesting, seed preparation and soil peparation.

            % this section loops through the whole months list and suffixes list to reset the lamp to default color
            for j = 1:length(suffixes)
                for i = 1:length(months)
                    app.([months{i}, suffixes{j}]).Color = defaultColor;
                end
            end
        end
    end


    methods (Access = public)
        % This function helps to indicate the range of month by switching up a set of specific lamps
        function setLampColors(app, timeRange, ~, suffix, defaultColor)

            % This changes the lamp to the default state incase any of them was still left on
            months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
            for i = 1:length(months)
                app.([months{i}, suffix]).Color = defaultColor;
            end

            % This makes sure that timeRange is a string
            timeRange = string(timeRange);

            % This divides the input to handle multiple month ranges like 'June - September', or single month
            if contains(timeRange, ' - ')
                timeParts = split(timeRange, ' - ');
                startMonth = timeParts(1);
                endMonth = timeParts(2);
            else
                % for Single month
                startMonth = timeRange;
                endMonth = timeRange;
            end

            % This converts the months to an index as respectively seen in the calendar
            monthOrder = {'January', 'February', 'March', 'April', 'May', 'June', 'July', 'August', 'September', 'October', 'November', 'December'};
            startIdx = find(strcmp(monthOrder, startMonth));
            endIdx = find(strcmp(monthOrder, endMonth));

            % This enables ranges that span over the year to be accounted for accurately (e.g., November to February)
            if endIdx < startIdx
                endIdx = endIdx + 12;
            end

            % This section loops through the range and set the lamps to green ('g')
            for i = startIdx:endIdx
                monthAbbr = months{mod(i-1, 12) + 1};
                app.([monthAbbr, suffix]).Color = 'g';
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Loading of all necessary datasets
            % Loading of all necessary datasets
            app.historicalData = readtable( ...
                fullfile(pwd, 'internal', 'Climate_Crop_Success_Data (1).csv'), ...
                'VariableNamingRule', 'preserve'); %Load Climate Data
            
            app.climateData = readtable( ...
                fullfile(pwd, 'dataset_to_import', 'Nigeria_Climate_Data.xlsx'), ...
                'VariableNamingRule', 'preserve'); % Load Climate and Crop
            
            app.cropRequirementsData = readtable( ...
                fullfile(pwd, 'internal', 'Crop_Requirements_Data (1).xlsx'), ...
                'VariableNamingRule', 'preserve'); %Load Crop Requirements
            
            app.soilCropData = readtable( ...
                fullfile(pwd, 'internal', 'Soil_Crop_1Data.csv'), ...
                'VariableNamingRule', 'preserve');            % Load soil crop data

        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            % This prompts the user to select a file
            [file, path] = uigetfile({'*.csv;*.xlsx'}, 'Select Climate Condition Dataset');
            filename = fullfile(path, file);
            [~, ~, ext] = fileparts(filename);

            % This Checks for valid file extension
            if ~(strcmp(ext, '.csv') || strcmp(ext, '.xlsx'))
                app.EditField_66.Value = 'Please upload a .csv or .xlsx file.';
                app.EditField_66.FontColor = [0, 0, 0]; % Default black color
                figure(app.UIFigure)
                return;
            end

            % This Reads the dataset temporarily for validation purposes
            try
                tempData = readtable(filename);
            catch
                app.EditField_66.Value = 'Error Reading file. Please ensure that the file is valid.';
                app.EditField_66.FontColor = [1, 0, 0]; % Red color
                figure(app.UIFigure)
                return;
            end

            % Validate column headers
            requiredColumns = {'Location', 'AvgTemperature__C_', 'AvgRainfall_mm_', 'AvgHumidity__', 'Longitude', 'Latitude'};
            if ~all(ismember(requiredColumns, tempData.Properties.VariableNames))
                app.EditField_66.Value = 'Invalid Climate Dataset. Missing required columns.';
                app.EditField_66.FontColor = [1, 0, 0]; % Red color
                figure(app.UIFigure);
                return;
            end

            % Extract unique locations from the dataset
            uniqueLocations = unique(tempData.Location);

            % Ensure the "Location" column contains valid strings
            if ~iscellstr(uniqueLocations) && ~isstring(uniqueLocations)
                app.EditField_66.Value = 'Invalid Climate Dataset: "Location" column must contain strings.';
                app.EditField_66.FontColor = [1, 0, 0]; % Red color
                figure(app.UIFigure)
                return;
            end

            % Update the dropdown menu with new locations
            app.LocationDropDown.Items = ['Select Location', uniqueLocations'];

            % Save the validated dataset to the app
            app.climateData = tempData;

            % Indicate success
            app.EditField_66.Value = 'Climate dataset successfully uploaded.';
            app.EditField_66.FontColor = [0, 0.5, 0]; % Green color
            figure(app.UIFigure)
        end

        % Image clicked function: Image2, Image3
        function ButtonPushed(app, event)
            app.Image2.ImageClickedFcn = app.ImportFileButton.ButtonPushedFcn;
        end

        % Button pushed function: ViewPersonalizedPlanButton
        function ViewPersonalizedPlanButtonPushed(app, event)

            % storing of the selected value
            selectedLocation = app.LocationDropDown.Value;
            selectedSoil = app.SoilTypeDropDown.Value;
            selectedCrop = app.CropDropDown.Value;
            soilPH = app.SoilPHDropDown.Value;
            waterRetention = app.WaterRetentionDropDown.Value;

            % Ensure all Fields are appropriately imputted
            if strcmp(selectedLocation, 'Select Location') || strcmp(selectedSoil, 'Select Soil') || strcmp(selectedCrop, 'Select Crop')
                app.Label.Text = 'Please Input all Fields';
            else
                % Filter climate data for selected location
                locationData = app.climateData(strcmp(app.climateData.Location, selectedLocation), :);

                % Check soil and crop compatibility
                isCompatible = strcmp(app.soilCropData.SoilType, selectedSoil) & strcmp(app.soilCropData.SuitableCrop, selectedCrop);

                if ~isCompatible
                    app.Label.Text = strjoin([selectedCrop + " isn't fit for " + selectedSoil]);
                else
                    % Fetch crop requirements
                    cropRequirements = app.cropRequirementsData(strcmp(app.cropRequirementsData.Crop, selectedCrop), :);

                    % Extract climate data for prediction
                    avgTemperature = locationData.AvgTemperature__C_;
                    avgRainfall = locationData.AvgRainfall_mm_;
                    avgHumidity = locationData.AvgHumidity__;

                    % Prepare input for Random Forest model
                    cropCodes = categorical(app.historicalData.Crop);
                    cropCodesNumeric = double(cropCodes);
                    X = [app.historicalData.AvgTemperature_C, app.historicalData.AvgRainfall_mm, app.historicalData.AvgHumidity_, cropCodesNumeric];
                    y = app.historicalData.CropSuccess;

                    % Train the Random Forest model
                    cv = cvpartition(size(X, 1), 'HoldOut', 0.2);
                    X_train = X(training(cv), :);
                    y_train = y(training(cv), :);
                    Mdl = TreeBagger(50, X_train, y_train, 'Method', 'classification', 'OOBPredictorImportance', 'on');

                    % Predict based on current climate conditions
                    cropID = find(strcmp(categories(cropCodes), selectedCrop));
                    X_predict = [avgTemperature, avgRainfall, avgHumidity, cropID];
                    [predictedLabel, ~] = predict(Mdl, X_predict);
                    predictedLabel = str2double(predictedLabel);

                    % Update the lamps based on prediction
                    if predictedLabel == 1
                        app.PlantingTimeEditField.Value = cropRequirements.Optimal_Planting_Month{1};
                        app.HarvestingtimeEditField.Value= cropRequirements.Optimal_Harvest_Month{1};
                        app.SoilPreparationTimeEditField.Value = cropRequirements.Soil_Preparation_Month{1};
                        app.SeedPreparationTimeEditField.Value = cropRequirements.Seed_Preparation_Month{1};
                        app.TextArea.Value = sprintf(['Climate condition suitable for planting. \n\nACTIONS FOR BETTER YIELD.\n\n' ...
                            ...
                            '1. Improve soil Moisture\n\n' ...
                            '2. Consider Irrigation\n\n' ...
                            '3. Use cover Crop\n\n' ...
                            '4. Monitor Weather Forecasts\n\n' ...
                            '5. Install Shade Nets']);

                    else
                        app.TextArea.Value = sprintf(['The climate conditions are not optimal for planting this crop at the moment. \n\n' ...
                            'RECOMMENDED ACTION\n'...
                            '1. Improve soil Moisture\n\n' ...
                            '2. consider Irrigation\n\n' ...
                            '3. Use cover Crop\n\n' ...
                            '4. Monitor Weather Forecasts\n\n' ...
                            '5. Install Shade Nets']);

                    end


                    % checking pH of the soil

                    if strcmp(waterRetention, 'Select Water Retention Ability') || strcmp(soilPH, 'Select Soil pH')
                        app.SoilpHRecommendationTextArea.Value = '';
                        app.WaterRetentionRecommendationTextArea.Value = '';
                    else

                        switch soilPH
                            case "Alkaline Soil (High pH)"
                                app.SoilpHRecommendationTextArea.Value = (' Ensure Minimal Supply of Manure');
                            case "Acidic soil (Low pH)"
                                app.SoilpHRecommendationTextArea.Value = (' Ensure Frequent Water supply');
                            otherwise
                                app.SoilpHRecommendationTextArea.Value = ('Ensure maximun supply of Manure');
                        end

                        %checking water Retention of the soil

                        switch waterRetention
                            case "Well Drained"
                                app.WaterRetentionRecommendationTextArea.Value = sprintf(' Continue to enrich the soil with organic matter');
                            case "Watter logged"
                                app.WaterRetentionRecommendationTextArea.Value = sprintf('1. Improve drainage raised beds \n\n2. Avoid organic matter to help absorb excess water \n\n3. plant deep-rooted crops.');
                            otherwise
                                app.WaterRetentionRecommendationTextArea.Value = sprintf('1. Use mulch to reduce evaporation and retain moisture \n\n2. Incorporate organic matter to improve water retention.');
                        end
                    end

                    % Update yield potential chart
                    stateData = app.historicalData(strcmp(app.historicalData.Location, selectedLocation), :);
                    crops = unique(stateData.Crop);
                    yield_potential = zeros(length(crops), 1);
                    for i = 1:length(crops)
                        crop_filter = strcmp(stateData.Crop, crops{i});
                        yield_potential(i) = mean(stateData.CropSuccess(crop_filter));
                    end
                    bar(app.UIAxes, categorical(crops), yield_potential);
                    title(app.UIAxes, ['Yield Potential for Crops in ', selectedLocation]);


                    % Filter climate data for the selected state
                    filteredData = app.climateData(strcmp(app.climateData.Location, selectedLocation), :);

                    % reset lamps before setting specific time
                    defaultColor = [0.94, 0.94, 0.94];

                    % planting time
                    PlantTime = app.PlantingTimeEditField.Value;
                    setLampColors(app, PlantTime, '', '_Planting', defaultColor);

                    % harvesting time
                    HarvestTime = app.HarvestingtimeEditField.Value;
                    setLampColors(app, HarvestTime, '', '_Harvest', defaultColor);

                    % seed preparation time
                    SeedTime = app.SeedPreparationTimeEditField.Value;
                    setLampColors(app, SeedTime, '', '_Seed_Preparation', defaultColor);

                    % soil preparation time
                    SoilTime = app.SoilPreparationTimeEditField.Value;
                    setLampColors(app, SoilTime, '', '_Soil_Preparation', defaultColor);

                    %this will delete existing geoaxes if any, to avoid overlapping maps
                    delete(findall(app.TemperatureMapPanel, 'Type', 'geoaxes'));

                    % Create a geoaxes inside the the temperature map panel
                    gax = geoaxes(app.TemperatureMapPanel);

                    % Plot temperature data using geoscatter
                    geoscatter(gax, filteredData.Latitude, filteredData.Longitude, 100, filteredData.AvgTemperature__C_, 'filled');

                    % this will add a colorbar to show temperature scale for better visualization
                    colorbar(gax);

                    % this will set the title of the map to reflect the selected location
                    title(gax, ['Current Temperature in ', selectedLocation]);

                    % convert or set the basemap to topographic
                    geobasemap(gax, 'topographic');

                    %this makes sure that the error code doesn'
                    app.Label.Text = '';

                end
            end
        end

        % Button pushed function: ReloadAppButton
        function ReloadAppButtonPushed(app, event)
            % this is the callback function of the reload button that clears all input so that the farmer can rerun the program


            % this will clear all input fields
            app.PlantingTimeEditField.Value = '';
            app.HarvestingtimeEditField.Value = '';
            app.SoilPreparationTimeEditField.Value = '';
            app.SeedPreparationTimeEditField.Value = '';

            %this helps to clear all text areas and recommendations
            app.TextArea.Value = '';
            app.SoilpHRecommendationTextArea.Value = '';
            app.WaterRetentionRecommendationTextArea.Value = '';
            app.EditField_66.Value = '';
            app.Label.Text = '';
            title(app.UIAxes, 'Yield Potential');

            % this will reset dropdowns to the default first item
            app.LocationDropDown.Value = app.LocationDropDown.Items{1};
            app.SoilTypeDropDown.Value = app.SoilTypeDropDown.Items{1};
            app.CropDropDown.Value = app.CropDropDown.Items{1};
            app.SoilPHDropDown.Value = app.SoilPHDropDown.Items{1};
            app.WaterRetentionDropDown.Value = app.WaterRetentionDropDown.Items{1};

            %this sections clears any plotted data on the UIAxes (Yield Potential Chart)

            % Clear the chart (bar plot)
            cla(app.UIAxes);

            % Delete existing geoaxes if any, to avoid overlapping maps
            delete(findall(app.TemperatureMapPanel, 'Type', 'geoaxes'));


            % Reset all lamps to the default color
            defaultColor = [0.94, 0.94, 0.94];
            resetAllLamps(app, defaultColor);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.Image.ImageClickedFcn = app.ReloadAppButton.ButtonPushedFcn;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Images', 'Farmland_Image.png');
            app.AIPredictedPlantingSchedulePanel = uipanel(app.UIFigure);
            app.AIPredictedPlantingSchedulePanel.Title = 'AI - Predicted Planting Schedule';
            app.ViewPersonalizedPlanButton = uibutton(app.AIPredictedPlantingSchedulePanel, 'push');
            app.ViewPersonalizedPlanButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPersonalizedPlanButtonPushed, true);
            app.ViewPersonalizedPlanButton.Text = 'View Personalized Plan';
            app.PlantingTimeEditFieldLabel = uilabel(app.AIPredictedPlantingSchedulePanel);
            app.PlantingTimeEditFieldLabel.Text = 'Planting Time';
            app.PlantingTimeEditField = uieditfield(app.AIPredictedPlantingSchedulePanel, 'text');
            app.PlantingTimeEditField.Editable = 'off';
            app.HarvestingtimeEditFieldLabel = uilabel(app.AIPredictedPlantingSchedulePanel);
            app.HarvestingtimeEditFieldLabel.Text = 'Harvesting time';
            app.HarvestingtimeEditField = uieditfield(app.AIPredictedPlantingSchedulePanel, 'text');
            app.HarvestingtimeEditField.Editable = 'off';
            app.SeedPreparationTimeEditFieldLabel = uilabel(app.AIPredictedPlantingSchedulePanel);
            app.SeedPreparationTimeEditFieldLabel.Text = 'Seed Preparation Time';
            app.SeedPreparationTimeEditField = uieditfield(app.AIPredictedPlantingSchedulePanel, 'text');
            app.SeedPreparationTimeEditField.Editable = 'off';
            app.SoilPreparationTimeEditFieldLabel = uilabel(app.AIPredictedPlantingSchedulePanel);
            app.SoilPreparationTimeEditFieldLabel.Text = 'Soil PreparationTime';
            app.SoilPreparationTimeEditField = uieditfield(app.AIPredictedPlantingSchedulePanel, 'text');
            app.SoilPreparationTimeEditField.Editable = 'off';
            app.AIPredictedCropPlanningSchedulePanel = uipanel(app.UIFigure);
            app.AIPredictedCropPlanningSchedulePanel.Title = 'AI - Predicted Crop Planning Schedule';
            app.EditField = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'January';
            app.EditField_2 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'Feburary';
            app.EditField_3 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 'March';
            app.EditField_4 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = 'April';
            app.EditField_5 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 'May';
            app.EditField_6 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'June';
            app.EditField_7 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 'July';
            app.EditField_8 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_8.Value = 'August';
            app.EditField_9 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_9.Editable = 'off';
            app.EditField_9.Value = 'September';
            app.EditField_10 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_10.Editable = 'off';
            app.EditField_10.Value = 'October';
            app.EditField_11 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_11.Editable = 'off';
            app.EditField_11.Value = 'November';
            app.EditField_12 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_12.Editable = 'off';
            app.EditField_12.Value = 'December';
            app.EditField_13 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_13.Editable = 'off';
            app.EditField_14 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_14.Editable = 'off';
            app.EditField_15 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_15.Editable = 'off';
            app.EditField_16 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_16.Editable = 'off';
            app.EditField_17 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_17.Editable = 'off';
            app.EditField_18 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_18.Editable = 'off';
            app.EditField_19 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_19.Editable = 'off';
            app.EditField_20 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_20.Editable = 'off';
            app.EditField_21 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_21.Editable = 'off';
            app.EditField_22 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_22.Editable = 'off';
            app.EditField_23 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_23.Editable = 'off';
            app.EditField_24 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_24.Editable = 'off';
            app.EditField_25 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_25.Editable = 'off';
            app.EditField_26 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_26.Editable = 'off';
            app.EditField_27 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_27.Editable = 'off';
            app.EditField_28 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_28.Editable = 'off';
            app.EditField_29 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_29.Editable = 'off';
            app.EditField_30 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_30.Editable = 'off';
            app.EditField_31 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_31.Editable = 'off';
            app.EditField_32 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_32.Editable = 'off';
            app.EditField_33 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_33.Editable = 'off';
            app.EditField_34 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_34.Editable = 'off';
            app.EditField_35 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_35.Editable = 'off';
            app.EditField_36 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_36.Editable = 'off';
            app.Jan_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Jan_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Feb_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Feb_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Mar_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Mar_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Apr_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Apr_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.May_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.May_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Jun_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Jun_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Jul_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Jul_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Aug_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Aug_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Sep_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Sep_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Oct_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Oct_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Nov_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Nov_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Dec_Planting = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Dec_Harvest = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.EditField_37 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_37.Editable = 'off';
            app.EditField_38 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_38.Editable = 'off';
            app.EditField_39 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_39.Editable = 'off';
            app.EditField_40 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_40.Editable = 'off';
            app.EditField_41 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_41.Editable = 'off';
            app.EditField_42 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_42.Editable = 'off';
            app.EditField_43 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_43.Editable = 'off';
            app.EditField_44 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_44.Editable = 'off';
            app.EditField_45 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_45.Editable = 'off';
            app.EditField_46 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_46.Editable = 'off';
            app.EditField_47 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_47.Editable = 'off';
            app.EditField_48 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_48.Editable = 'off';
            app.EditField_49 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_49.Editable = 'off';
            app.EditField_50 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_50.Editable = 'off';
            app.EditField_51 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_51.Editable = 'off';
            app.EditField_52 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_52.Editable = 'off';
            app.EditField_53 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_53.Editable = 'off';
            app.EditField_54 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_54.Editable = 'off';
            app.EditField_55 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_55.Editable = 'off';
            app.EditField_56 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_56.Editable = 'off';
            app.EditField_57 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_57.Editable = 'off';
            app.EditField_58 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_58.Editable = 'off';
            app.EditField_59 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_59.Editable = 'off';
            app.EditField_60 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_60.Editable = 'off';
            app.Jan_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Jan_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Feb_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Feb_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Mar_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Mar_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Apr_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Apr_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.May_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.May_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Jun_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Jun_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Jul_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Jul_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Aug_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Aug_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Sep_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Sep_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Oct_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Oct_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Nov_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Nov_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Dec_Soil_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.Dec_Seed_Preparation = uilamp(app.AIPredictedCropPlanningSchedulePanel);
            app.EditField_61 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_61.Editable = 'off';
            app.EditField_61.Value = 'Planting Time';
            app.EditField_62 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_62.Editable = 'off';
            app.EditField_62.Value = 'Harvesting Time';
            app.EditField_63 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_63.Editable = 'off';
            app.EditField_63.Value = 'Soil Preparation';
            app.EditField_64 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_64.Editable = 'off';
            app.EditField_64.Value = 'Seed Preparation';
            app.EditField_65 = uieditfield(app.AIPredictedCropPlanningSchedulePanel, 'text');
            app.EditField_65.Editable = 'off';
            app.EditField_65.Value = 'Month';
            app.RecommendationsbasedonAIpredictionPanel = uipanel(app.UIFigure);
            app.RecommendationsbasedonAIpredictionPanel.Title = 'Recommendations based on AI prediction';
            app.TextArea = uitextarea(app.RecommendationsbasedonAIpredictionPanel);
            app.TextArea.Editable = 'off';
            app.TemperatureMapPanel = uipanel(app.UIFigure);
            app.TemperatureMapPanel.Title = 'Temperature Map';
            app.SectionOne = uipanel(app.UIFigure);
            app.SectionOne.Title = 'Input Details';
            app.LocationDropDownLabel = uilabel(app.SectionOne);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.SectionOne);
            app.LocationDropDown.Items = {'Select Location', 'Abia', 'Adamawa', 'Akwa Ibom', 'Anambra', 'Bauchi', 'Bayelsa', 'Benue', 'Borno', 'Cross River', 'Delta', 'Ebonyi', 'Edo', 'Ekiti', 'Enugu', 'Gombe', 'Imo', 'Jigawa', 'Kaduna', 'Kano', 'Katsina', 'Kebbi', 'Kogi', 'Kwara', 'Lagos', 'Nasarawa', 'Niger', 'Ogun', 'Ondo', 'Osun', 'Oyo', 'Plateau', 'Rivers', 'Sokoto', 'Taraba', 'Yobe', 'Zamfara', 'FCT'};
            app.LocationDropDown.Value = 'Select Location';
            app.SoilTypeDropDownLabel = uilabel(app.SectionOne);
            app.SoilTypeDropDownLabel.Text = 'Soil Type';
            app.SoilTypeDropDown = uidropdown(app.SectionOne);
            app.SoilTypeDropDown.Items = {'Select Soil', 'Loamy Soil', 'Clay Loam', 'Peaty Soil', 'Silty Soil', 'Sandy Loam'};
            app.SoilTypeDropDown.Value = 'Select Soil';
            app.CropDropDownLabel = uilabel(app.SectionOne);
            app.CropDropDownLabel.Text = 'Crop';
            app.CropDropDown = uidropdown(app.SectionOne);
            app.CropDropDown.Items = {'Select Crop', 'Beans', 'Maize', 'Yam', 'Rice', 'Potato', 'Cassava', 'Okra', 'Tomato', 'Cotton', 'Cocoa', 'Sweet Potato'};
            app.CropDropDown.Value = 'Select Crop';
            app.Label = uilabel(app.SectionOne);
            app.Label.Text = '';
            app.SectionOne_2 = uipanel(app.UIFigure);
            app.SectionOne_2.Title = 'Additional Details';
            app.SoilPHDropDownLabel = uilabel(app.SectionOne_2);
            app.SoilPHDropDownLabel.Text = 'Soil PH';
            app.SoilPHDropDown = uidropdown(app.SectionOne_2);
            app.SoilPHDropDown.Items = {'Select Soil pH', 'Acidic Soil (Low pH)', 'Alkaline Soil (High pH)', 'Neutral'};
            app.SoilPHDropDown.Value = 'Select Soil pH';
            app.WaterRetentionDropDownLabel = uilabel(app.SectionOne_2);
            app.WaterRetentionDropDownLabel.Text = 'Water Retention';
            app.WaterRetentionDropDown = uidropdown(app.SectionOne_2);
            app.WaterRetentionDropDown.Items = {'Select Water Retention Ability', 'Water Logged', 'Well Drained', 'Dry'};
            app.WaterRetentionDropDown.Value = 'Select Water Retention Ability';
            app.SoilpHRecommendationLabel = uilabel(app.SectionOne_2);
            app.SoilpHRecommendationLabel.Text = 'Soil pH Recommendation';
            app.SoilpHRecommendationTextArea = uitextarea(app.SectionOne_2);
            app.SoilpHRecommendationTextArea.Editable = 'off';
            app.WaterRetentionRecommendationLabel = uilabel(app.SectionOne_2);
            app.WaterRetentionRecommendationLabel.Text = {' Water Retention'; ' Recommendation'};
            app.WaterRetentionRecommendationTextArea = uitextarea(app.SectionOne_2);
            app.WaterRetentionRecommendationTextArea.Editable = 'off';
            app.ImportFileButton = uibutton(app.UIFigure, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File     ';
            app.AIPredictedCropYieldPotentialinSelectedLocationPanel = uipanel(app.UIFigure);
            app.AIPredictedCropYieldPotentialinSelectedLocationPanel.Title = 'Artificial Intelligence - Predicted Crop Yield Potential in Selected Location';
            app.UIAxes = uiaxes(app.AIPredictedCropYieldPotentialinSelectedLocationPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Crops')
            ylabel(app.UIAxes, 'Yield Potential')
            app.ReloadAppButton = uibutton(app.UIFigure, 'push');
            app.ReloadAppButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadAppButtonPushed, true);
            app.ReloadAppButton.Text = 'Reload App';
            app.EditField_66 = uieditfield(app.UIFigure, 'text');
            app.EditField_66.Editable = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'Reload_Image.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Import_image.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_CROP_PLANNER1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end